classdef HealthVision < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SmarthomecaremonitorUIFigure  matlab.ui.Figure
        HeaderPanel                   matlab.ui.container.Panel
        AgeEditField                  matlab.ui.control.NumericEditField
        UserEditField                 matlab.ui.control.EditField
        HeightEditField               matlab.ui.control.NumericEditField
        WeightEditField               matlab.ui.control.NumericEditField
        UserLabel                     matlab.ui.control.Label
        HeightLabel                   matlab.ui.control.Label
        WeightLabel                   matlab.ui.control.Label
        AgeLabel                      matlab.ui.control.Label
        TitleLabel                    matlab.ui.control.Label
        MainPanel                     matlab.ui.container.Panel
        ButtonPanel                   matlab.ui.container.Panel
        ECGButton                     matlab.ui.control.StateButton
        PPGLabel                      matlab.ui.control.Label
        PPGButton                     matlab.ui.control.StateButton
        StepsLabel                    matlab.ui.control.Label
        StepsButton                   matlab.ui.control.StateButton
        ECGLabel                      matlab.ui.control.Label
        InfoPanel                     matlab.ui.container.Panel
        PPGPanel                      matlab.ui.container.Panel
        PPGAxesDetail1                matlab.ui.control.UIAxes
        PPGAxesDetail3                matlab.ui.control.UIAxes
        SPO2ValueEditField            matlab.ui.control.NumericEditField
        PPGLowLamp                    matlab.ui.control.Lamp
        PPGNormalLamp                 matlab.ui.control.Lamp
        PPGStatisticsButtonGroup      matlab.ui.container.ButtonGroup
        PPGButton30m                  matlab.ui.control.RadioButton
        PPGButton2h                   matlab.ui.control.RadioButton
        PPGButton6h                   matlab.ui.control.RadioButton
        PPGLastTimeLabel              matlab.ui.control.Label
        PPGMinEditField               matlab.ui.control.NumericEditField
        PPGAvgEditField               matlab.ui.control.NumericEditField
        PPGAxesDetail2                matlab.ui.control.UIAxes
        PPGMaxEditField               matlab.ui.control.NumericEditField
        PPGMinLabel                   matlab.ui.control.Label
        PPGMaxLabel                   matlab.ui.control.Label
        PPGAverageLabel               matlab.ui.control.Label
        PPGLowLabel                   matlab.ui.control.Label
        PPGNormalLabel                matlab.ui.control.Label
        StepsPanel                    matlab.ui.container.Panel
        ECGPanel                      matlab.ui.container.Panel
        AvgEditField                  matlab.ui.control.NumericEditField
        ECGLastTimeLabel              matlab.ui.control.Label
        MinEditField                  matlab.ui.control.NumericEditField
        MaxEditField                  matlab.ui.control.NumericEditField
        LowLamp                       matlab.ui.control.Lamp
        NormalLamp                    matlab.ui.control.Lamp
        HighLamp                      matlab.ui.control.Lamp
        ECGAxesDetail                 matlab.ui.control.UIAxes
        ECGMinLabel                   matlab.ui.control.Label
        ECGMaxLabel                   matlab.ui.control.Label
        ECGAvgLabel                   matlab.ui.control.Label
        ECGLowLabel                   matlab.ui.control.Label
        ECGNormalLabel                matlab.ui.control.Label
        ECGHighLabel                  matlab.ui.control.Label
        ECGStatisticsButtonGroup      matlab.ui.container.ButtonGroup
        minButton                     matlab.ui.control.RadioButton
        hrButton_3                    matlab.ui.control.RadioButton
        hrButton_2                    matlab.ui.control.RadioButton
        Label                         matlab.ui.control.Label
        Gauge                         matlab.ui.control.SemicircularGauge
        GraphPanel                    matlab.ui.container.Panel
        ECGAxesHome                   matlab.ui.control.UIAxes
        PPGAxesHome                   matlab.ui.control.UIAxes
        StepsAxesHome                 matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Value changed function: ECGButton
        function ECGButtonValueChanged(app, event)
            value = app.ECGButton.Value;
            if value == true
                app.ECGButton.BackgroundColor = [0.8, 0.8, 0.8];
                app.PPGButton.BackgroundColor = [0.15, 0.18, 0.23];
                app.StepsButton.BackgroundColor = [0.15, 0.18, 0.23];
                
                app.PPGButton.Value = false;
                app.StepsButton.Value = false;
                
                app.GraphPanel.Visible = 'off';
                app.PPGPanel.Visible = 'off';
                app.StepsPanel.Visible = 'off';
                
                app.ECGPanel.Visible = 'on';
            else
                app.ECGButton.BackgroundColor = [0.15, 0.18, 0.23];
                
                app.ECGPanel.Visible = 'off';
                app.GraphPanel.Visible = 'on';
            end
        end

        % Selection changed function: ECGStatisticsButtonGroup
        function ECGStatisticsButtonGroupSelectionChanged(app, event)
            selectedButton = app.ECGStatisticsButtonGroup.SelectedObject;
            if app.minButton.Value == true
                app.LastminuteLabel.Visible = 'on';
                app.Last10minutesLabel.Visible = 'off';
                app.ECGLastTimeLabel.Visible = 'off';
            elseif app.hrButton_3.Value == true
                app.LastminuteLabel.Visible = 'off';
                app.Last10minutesLabel.Visible = 'on';
                app.ECGLastTimeLabel.Visible = 'off';
            else
                app.LastminuteLabel.Visible = 'off';
                app.Last10minutesLabel.Visible = 'off';
                app.ECGLastTimeLabel.Visible = 'on';
            end
        end

        % Callback function
        function MoredetailsButtonPushed(app, event)
            app.ExtraInformationECGPanel.Visible = 'on';
            app.MoredetailsButton.Visible = 'off';
        end

        % Callback function
        function MoredetailsButton_2Pushed(app, event)
            app.ExtraInformationPPGPanel.Visible = 'on';
            app.MoredetailsButton_2.Visible = 'off';
        end

        % Value changed function: PPGButton
        function PPGButtonValueChanged(app, event)
            value = app.PPGButton.Value;
            if value == true
                app.PPGButton.BackgroundColor = [0.8, 0.8, 0.8];
                app.ECGButton.BackgroundColor = [0.15, 0.18, 0.23];
                app.StepsButton.BackgroundColor = [0.15, 0.18, 0.23];
                
                app.ECGButton.Value = false;
                app.StepsButton.Value = false;
                
                app.GraphPanel.Visible = 'off';
                app.ECGPanel.Visible = 'off';
                app.StepsPanel.Visible = 'off';
                
                app.PPGPanel.Visible = 'on';
            else
                app.PPGButton.BackgroundColor = [0.15, 0.18, 0.23];
                
                app.PPGPanel.Visible = 'off';
                app.GraphPanel.Visible = 'on';
            end
        end

        % Selection changed function: PPGStatisticsButtonGroup
        function PPGStatisticsButtonGroupSelectionChanged(app, event)
            selectedButton = app.PPGStatisticsButtonGroup.SelectedObject;
            if app.PPGButton30m.Value == true
                app.PPGLastTimeLabel.Text = 'Last 30 mins';
            elseif app.PPGButton2h.Value == true
                app.PPGLastTimeLabel.Text = 'Last 2 hrs';
            else
                app.PPGLastTimeLabel.Text = 'Last 6 hrs';
            end 
        end

        % Size changed function: SmarthomecaremonitorUIFigure
        function SmarthomecaremonitorUIFigureSizeChanged(app, event)
            position = app.SmarthomecaremonitorUIFigure.Position;
            
            w = position(3);
            h = position(4);
            
            % Automatically adjust GUI size
            
            
            % GUI size factor
            GUI_x = 0.0/1200.0;
            GUI_w = 1200.0/1200.0;
            GUI_y = 0.0/700.0;
            GUI_h = 700.0/700.0;
            
            
            % Header size factor
            Header_x = 0.0/1200.0;
            Header_w = 1200.0/1200.0;
            Header_y = 600.0/700.0;
            Header_h = 100.0/700.0;
            
            
            % Header Panel components
            Title_width = 213;
            Title_height = 47;
            Title_xpos = Header_w*w/2 - Title_width/2;
            Title_ypos = Header_h*h/2 - Title_height/2;
            
            UserEF_xpos = 100;
            UserEF_ypos = Header_h*h/2 - 15;
            UserEF_width = 200;
            UserEF_height = 31;
            
            UserL_width = 50;
            UserL_height = 31;
            UserL_xpos = UserEF_xpos - UserL_width;
            UserL_ypos = UserEF_ypos;
            
            inputEF_ySpace = 25;
            
            inputL_width = 50;
            inputL_height = 20;
            inputEF_width = 100;
            inputEF_height = 22;
            
            inputL_xpos = max([UserEF_xpos + UserEF_width, w - inputL_width - inputEF_width - 25]);
            inputL_ypos = Header_h*h/2 + inputEF_ySpace/2;
            inputEF_xpos = inputL_xpos + inputL_width;
            inputEF_ypos = Header_h*h/2 + inputEF_ySpace/2;
            
            
            % Main Panel size factor
            Main_x= 0.0/1200.0;
            Main_w = 1200.0/1200.0;
            Main_y= 0.0/700.0;
            Main_h = 600.0/700.0;
            
            
            % Button Panel size factor
            Button_x = 0.0/1200.0;
            Button_w = 220.0/1200.0;
            but_L_x = 10.0/1200.0;
            but_L_w = 72.0/1200.0;
            but_B_x = 85.0/1200.0;
            but_B_w = 125.0/1200.0;
            
            ySpace = 200.0/700.0;
            
            Button_y = 0.0/700.0;
            Button_h = 600.0/700.0;
            but_L_y = 80.0/700.0;
            but_L_h = 48.0/700.0;
            but_B_y = 50.0/700.0;
            but_B_h = 125.0/700.0;
            
            
            % Info Panel size factor
            Info_x = 220.0/1200.0;
            Info_w = 980.0/1200.0;
            subInfo_x = 0.0/1200.0;
            subInfo_w = 980.0/1200.0;
            Info_y = 0.0/700.0;
            Info_h = 600.0/700.0;
            subInfo_y = 0.0/700.0;
            subInfo_h = 600.0/700.0;
            
            graph_x = 15.0/1200.0;
            graph_w = 950.0/1200.0;
            graph_y = 25.0/700.0;
            graph_h = 175.0/700.0;
            
            
            % PPG
            PPG_UI3_x = 0.0/930.0;
            PPG_UI3_w = 700.0/930.0;
            PPG_UI3_y = 270.0/450.0;
            PPG_UI3_h = 120.0/450.0;
            
            PPG_UI4_x = 0.0/930.0;
            PPG_UI4_w = 700.0/930.0;
            PPG_UI4_y = 150.0/450.0;
            PPG_UI4_h = 120.0/450.0;
            
            PPG_UI6_x = 340.0/930.0;
            PPG_UI6_w = 550.0/930.0;
            PPG_UI6_y = 10.0/450.0;
            PPG_UI6_h = 150.0/450.0;
            
            PPG_SPO2EF_x = 700.0/930.0;
            PPG_SPO2EF_w = 80.0/930.0;
            PPG_SPO2EF_y = 250.0/450.0;
            PPG_SPO2EF_h =  80.0/450.0;
            
            PPG_MinEF_x = 210.0/930.0;
            PPG_MinEF_w = 100.0/930.0;
            PPG_MinEF_y = 100.0/450.0;
            PPG_MinEF_h = 30.0/450.0;
            
            PPG_MinL_x = 150.0/930.0;
            PPG_MinL_w = 50.0/930.0;
            PPG_MinL_y = 100.0/450.0;
            PPG_MinL_h = 32.0/450.0;
            
            PPG_AvgEF_x = 210.0/930.0;
            PPG_AvgEF_w = 100.0/930.0;
            PPG_AvgEF_y = 30.0/930.0;
            PPG_AvgEF_h = 30.0/450.0;
            
            PPG_AvgL_x = 150.0/930.0;
            PPG_AvgL_w = 50.0/930.0;
            PPG_AvgL_y = 30.0/450.0;
            PPG_AvgL_h = 32.0/450.0;
            
            PPG_MaxEF_x = 210.0/930.0;
            PPG_MaxEF_w = 100.0/930.0;
            PPG_MaxEF_y = 65.0/450.0;
            PPG_MaxEF_h = 30.0/450.0;
            
            PPG_MaxL_x = 150.0/930.0;
            PPG_MaxL_w = 50.0/930.0;
            PPG_MaxL_y = 65.0/450.0;
            PPG_MaxL_h = 32.0/450.0;
            
            PPG_LowLamp_x = 880.0/930.0;
            PPG_LowLamp_w = 20.0/930.0;
            PPG_LowLamp_y = 300.0/450.0;
            PPG_LowLamp_h = 20.0/450.0;
            
            PPG_LowL_x = 800.0/930.0;
            PPG_LowL_w = 50.0/930.0;
            PPG_LowL_y = 300.0/450.0;
            PPG_LowL_h = 32.0/450.0;
            
            PPG_NormalLamp_x = 880.0/930.0;
            PPG_NormalLamp_w = 20.0/930.0;
            PPG_NormalLamp_y = 270.0/450.0;
            PPG_NormalLamp_h = 20.0/450.0;
                        
            PPG_NormalL_x = 800.0/930.0;
            PPG_NormalL_w = 70.0/930.0;
            PPG_NormalL_y = 260.0/450.0;
            PPG_NormalL_h = 32.0/450.0;
            
            PPG_StatisticsBG_x = 10.0/930.0;
            PPG_StatisticsBG_w = 100.0/930.0;
            PPG_StatisticsBG_y = 30.0/450.0;
            PPG_StatisticsBG_h = 140.0/450.0;
            
            PPG_LastTime_x = 180.0/930.0;
            PPG_LastTime_w = 75.0/930.0;
            PPG_LastTime_y = 140.0/450.0;
            PPG_LastTime_h = 32.0/450.0;
           
      
            % ECG
            ECG_AvgEF_x = 300.0/920.0;
            ECG_AvgEF_w = 100.0/920.0;
            ECG_AvgEF_y = 180.0/400.0;
            ECG_AvgEF_h = 30.0/400.0;
            
            ECG_AvgL_x = 250.0/920.0;
            ECG_AvgL_w = 40.0/920.0;
            ECG_AvgL_y = 180.0/400.0;
            ECG_AvgL_h = 32.0/400.0;
            
            ECG_MinEF_x = 300.0/920.0;
            ECG_MinEF_w = 100.0/920.0;
            ECG_MinEF_y = 260.0/400.0;
            ECG_MinEF_h = 30.0/400.0;
            
            ECG_MinL_x = 250.0/920.0;
            ECG_MinL_w = 40.0/920.0;
            ECG_MinL_y = 260.0/400.0;
            ECG_MinL_h = 32.0/400.0;
            
            ECG_MaxEF_x = 300.0/920.0;
            ECG_MaxEF_w = 100.0/920.0;
            ECG_MaxEF_y = 225.0/400.0;
            ECG_MaxEF_h = 30.0/400.0;
            
            ECG_MaxL_x = 250.0/920.0;
            ECG_MaxL_w = 45.0/920.0;
            ECG_MaxL_y = 225.0/400.0;
            ECG_MaxL_h = 32.0/400.0;
            
            ECG_Gauge_x = 450.0/920.0;
            ECG_Gauge_w = 220.0/920.0;
            ECG_Gauge_y = 180.0/400.0;
            ECG_Gauge_h = 120.0/400.0;
            
            ECG_Low_x = 825.0/920.0;
            ECG_Low_w = 20.0/920.0;
            ECG_Low_y = 265.0/400.0;
            ECG_Low_h = 20.0/400.0;
            
            ECG_LowL_x = 755.0/920.0;
            ECG_LowL_w = 45.0/920.0;
            ECG_LowL_y = 260.0/920.0;
            ECG_LowL_h = 32.0/920.0;
            
            ECG_Normal_x = 825.0/920.0;
            ECG_Normal_w = 20.0/920.0;
            ECG_Normal_y = 225.0/400.0;
            ECG_Normal_h = 20.0/400.0;
            
            ECG_NormalL_x = 755.0/920.0;
            ECG_NormalL_w = 70.0/920.0;
            ECG_NormalL_y = 220.0/920.0;
            ECG_NormalL_h = 32.0/920.0;
            
            ECG_High_x = 825.0/920.0;
            ECG_High_w = 20.0/920.0;
            ECG_High_y = 180.0/400.0;
            ECG_High_h = 20.0/400.0;
            
            ECG_HighL_x = 755.0/920.0;
            ECG_HighL_w = 50.0/920.0;
            ECG_HighL_y = 175.0/920.0;
            ECG_HighL_h = 32.0/920.0;
            
            ECG_LastTimeL_x = 300.0/920.0;
            ECG_LastTimeL_w = 110.0/920.0;
            ECG_LastTimeL_y = 300.0/400.0;
            ECG_LastTimeL_h = 32.0/400.0;
            
            ECG_UIAxes2_x = 40.0/920.0;
            ECG_UIAxes2_w = 830.0/920.0;
            ECG_UIAxes2_y = 0.0/400.0;
            ECG_UIAxes2_h = 165.0/400.0;
            
            ECG_Statistics_x = 32.0/920.0;
            ECG_Statistics_w = 130.0/920.0;
            ECG_Statistics_y = 180.0/400.0;
            ECG_Statistics_h = 145.0/400.0;


            % Set the positions
            
            % 1) Header Panel
            app.HeaderPanel.Position = uint16([Header_x*w,Header_y*h,Header_w*w,Header_h*h]);
            
            app.TitleLabel.Position = uint16([Title_xpos, Title_ypos, Title_width, Title_height]);
            
            app.UserEditField.Position = uint16([UserEF_xpos, UserEF_ypos, UserEF_width, UserEF_height]);
            app.UserLabel.Position = uint16([UserL_xpos, UserL_ypos, UserL_width, UserL_height]);
            
            app.AgeEditField.Position = uint16([inputEF_xpos, inputEF_ypos, inputEF_width, inputEF_height]);
            app.HeightEditField.Position = uint16([inputEF_xpos, inputEF_ypos - inputEF_ySpace, inputEF_width, inputEF_height]);
            app.WeightEditField.Position = uint16([inputEF_xpos, inputEF_ypos - 2*inputEF_ySpace, inputEF_width, inputEF_height]);
            
            app.AgeLabel.Position = uint16([inputL_xpos, inputL_ypos, inputL_width, inputL_height]);
            app.HeightLabel.Position = uint16([inputL_xpos, inputL_ypos - inputEF_ySpace, inputL_width, inputL_height]);
            app.WeightLabel.Position = uint16([inputL_xpos, inputL_ypos - 2*inputEF_ySpace, inputL_width, inputL_height]);
            
            % 2) Button Panel
            app.MainPanel.Position = uint16([Main_x*w,Main_y*h,Main_w*w,Main_h*h]);
            app.ButtonPanel.Position = uint16([Button_x*w,Button_y*h,Button_w*w,Button_h*h]);
            app.ECGButton.Position = uint16([but_B_x*w,(but_B_y+2*ySpace)*h,but_B_w*w,but_B_h*h]);
            app.ECGLabel.Position = uint16([but_L_x*w,(but_L_y+2*ySpace)*h,but_L_w*w,but_L_h*h]);
            app.PPGButton.Position = uint16([but_B_x*w,(but_B_y+ySpace)*h,but_B_w*w,but_B_h*h]);
            app.PPGLabel.Position = uint16([but_L_x*w,(but_L_y+ySpace)*h,but_L_w*w,but_L_h*h]);
            app.StepsButton.Position = uint16([but_B_x*w,but_B_y*h,but_B_w*w,but_B_h*h]);
            app.StepsLabel.Position = uint16([but_L_x*w,but_L_y*h,but_L_w*w,but_L_h*h]);
            
            % 3) Visual Info Panel
            app.InfoPanel.Position = uint16([Info_x*w,Info_y*h,Info_w*w,Info_h*h]);
            
            % 3.1) Graph Panel
            app.GraphPanel.Position = uint16([subInfo_x*w,subInfo_y*h,subInfo_w*w,subInfo_h*h]);
            app.ECGAxesHome.Position = uint16([graph_x*w,(graph_y+2*ySpace)*h,graph_w*w,graph_h*h]);
            app.PPGAxesHome.Position = uint16([graph_x*w,(graph_y+ySpace)*h,graph_w*w,graph_h*h]);
            app.StepsAxesHome.Position = uint16([graph_x*w,graph_y*h,graph_w*w,graph_h*h]);    
            
            % 3.2) PPG Panel
            app.PPGPanel.Position = uint16([subInfo_x*w,subInfo_y*h,subInfo_w*w,subInfo_h*h]);
            app.PPGAxesDetail1.Position = uint16([PPG_UI3_x*w,PPG_UI3_y*h,PPG_UI3_w*w,PPG_UI3_h*h]);
            app.PPGAxesDetail2.Position = uint16([PPG_UI4_x*w,PPG_UI4_y*h,PPG_UI4_w*w,PPG_UI4_h*h]);
            app.PPGAxesDetail3.Position = uint16([PPG_UI6_x*w,PPG_UI6_y*h,PPG_UI6_w*w,PPG_UI6_h*h]);
            app.SPO2ValueEditField.Position = uint16([PPG_SPO2EF_x*w,PPG_SPO2EF_y*h,PPG_SPO2EF_w*w,PPG_SPO2EF_h*h]);
            app.PPGMinEditField.Position = uint16([PPG_MinEF_x*w,PPG_MinEF_y*h,PPG_MinEF_w*w,PPG_MinEF_h*w]);
            app.PPGAvgEditField.Position = uint16([PPG_AvgEF_x*w,PPG_AvgEF_y*h,PPG_AvgEF_w*w,PPG_AvgEF_h*w]);
            app.PPGMaxEditField.Position = uint16([PPG_MaxEF_x*w,PPG_MaxEF_y*h,PPG_MaxEF_w*w,PPG_MaxEF_h*w]);
            app.PPGMinLabel.Position = uint16([PPG_MinL_x*w,PPG_MinL_y*h,PPG_MinL_w*w,PPG_MinL_h*w]);
            app.AvgLabel.Position = uint16([PPG_AvgL_x*w,PPG_AvgL_y*h,PPG_AvgL_w*w,PPG_AvgL_h*w]);
            app.PPGMaxLabel.Position = uint16([PPG_MaxL_x*w,PPG_MaxL_y*h,PPG_MaxL_w*w,PPG_MaxL_h*w]);
            app.PPGLowLamp.Position = uint16([PPG_LowLamp_x*w,PPG_LowLamp_y*h,PPG_LowLamp_w*w,PPG_LowLamp_h*h]);
            app.PPGNormalLamp.Position = uint16([PPG_NormalLamp_x*w,PPG_NormalLamp_y*h,PPG_NormalLamp_w*w,PPG_NormalLamp_h*h]);
            app.PPGLowLabel.Position = uint16([PPG_LowL_x*w,PPG_LowL_y*h,PPG_LowL_w*w,PPG_LowL_h*h]);
            app.PPGNormalLabel.Position = uint16([PPG_NormalL_x*w,PPG_NormalL_y*h,PPG_NormalL_w*w,PPG_NormalL_h*h]);
            app.StatisticsBG.Position = uint16([PPG_StatisticsBG_x*w,PPG_StatisticsBG_y*h,PPG_StatisticsBG_w*w,PPG_StatisticsBG_h*h]);
            app.PPGLastTimeLabel.Position = uint16([PPG_LastTime_x*w,PPG_LastTime_y*h,PPG_LastTime_w*w,PPG_LastTime_y*h]);
            
            % 3.3) ECG Panel
            app.ECGPanel.Position = uint16([subInfo_x*w,subInfo_y*h,subInfo_w*w,subInfo_h*h]);
            app.AvgEditField.Position = uint16([ECG_AvgEF_x*w,ECG_AvgEF_y*h,ECG_AvgEF_w*w,ECG_AvgEF_h*h]);
            app.MinEditField.Position = uint16([ECG_MinEF_x*w,ECG_MinEF_y*h,ECG_MinEF_w*w,ECG_MinEF_h*h]);
            app.MaxEditField.Position = uint16([ECG_MaxEF_x*w,ECG_MaxEF_y*h,ECG_MaxEF_w*w,ECG_MaxEF_h*h]);
            app.ECGAvgLabel.Position = uint16([ECG_AvgL_x*w,ECG_AvgL_y*h,ECG_AvgL_w*w,ECG_AvgL_h*h]);
            app.ECGMinLabel.Position = uint16([ECG_MinL_x*w,ECG_MinL_y*h,ECG_MinL_w*w,ECG_MinL_h*h]);
            app.ECGMaxLabel.Position = uint16([ECG_MaxL_x*w,ECG_MaxL_y*h,ECG_MaxL_w*w,ECG_MaxL_h*h]);
            app.Gauge.Position = uint16([ECG_Gauge_x*w,ECG_Gauge_y*h,ECG_Gauge_w*w,ECG_Gauge_h*h]);
            
            
            ECG_Low_x = 825.0/920.0;
            ECG_Low_w = 20.0/920.0;
            ECG_Low_y = 265.0/400.0;
            ECG_Low_h = 20.0/400.0;
            
            ECG_LowL_x = 755.0/920.0;
            ECG_LowL_w = 45.0/920.0;
            ECG_LowL_y = 260.0/920.0;
            ECG_LowL_h = 32.0/920.0;
            
            ECG_Normal_x = 825.0/920.0;
            ECG_Normal_w = 20.0/920.0;
            ECG_Normal_y = 225.0/400.0;
            ECG_Normal_h = 20.0/400.0;
            
            ECG_NormalL_x = 755.0/920.0;
            ECG_NormalL_w = 70.0/920.0;
            ECG_NormalL_y = 220.0/920.0;
            ECG_NormalL_h = 32.0/920.0;
            
            ECG_High_x = 825.0/920.0;
            ECG_High_w = 20.0/920.0;
            ECG_High_y = 180.0/400.0;
            ECG_High_h = 20.0/400.0;
            
            ECG_HighL_x = 755.0/920.0;
            ECG_HighL_w = 50.0/920.0;
            ECG_HighL_y = 175.0/920.0;
            ECG_HighL_h = 32.0/920.0;
            
            ECG_LastTimeL_x = 300.0/920.0;
            ECG_LastTimeL_w = 110.0/920.0;
            ECG_LastTimeL_y = 300.0/400.0;
            ECG_LastTimeL_h = 32.0/400.0;
            
            ECG_UIAxes2_x = 40.0/920.0;
            ECG_UIAxes2_w = 830.0/920.0;
            ECG_UIAxes2_y = 0.0/400.0;
            ECG_UIAxes2_h = 165.0/400.0;
            
            ECG_Statistics_x = 32.0/920.0;
            ECG_Statistics_w = 130.0/920.0;
            ECG_Statistics_y = 180.0/400.0;
            ECG_Statistics_h = 145.0/400.0;
        
            % 3.4) Steps Panel
            app.StepsPanel.Position = uint16([subInfo_x*w,subInfo_y*h,subInfo_w*w,subInfo_h*h]);


            %app.ECGButton.Text = 'hello';
            
            
        end

        % Value changed function: StepsButton
        function StepsButtonValueChanged(app, event)
            value = app.StepsButton.Value;
            if value == true
                app.StepsButton.BackgroundColor = [0.8, 0.8, 0.8];
                app.ECGButton.BackgroundColor = [0.15, 0.18, 0.23];
                app.PPGButton.BackgroundColor = [0.15, 0.18, 0.23];
                
                app.ECGButton.Value = false;
                app.PPGButton.Value = false;
                
                app.GraphPanel.Visible = 'off';
                app.PPGPanel.Visible = 'off';
                app.ECGPanel.Visible = 'off';
                
                app.StepsPanel.Visible = 'on';
            else
                app.StepsButton.BackgroundColor = [0.15, 0.18, 0.23];
                
                app.StepsPanel.Visible = 'off';
                app.GraphPanel.Visible = 'on';
            end
        end

        % Callback function
        function XButtonPushed(app, event)
            app.ExtraInformationECGPanel.Visible = 'off';
            app.MoredetailsButton.Visible = 'on'; 
        end

        % Callback function
        function XButton_2Pushed(app, event)
            app.ExtraInformationPPGPanel.Visible = 'off';
            app.MoredetailsButton_2.Visible = 'on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SmarthomecaremonitorUIFigure = uifigure;
            app.SmarthomecaremonitorUIFigure.Name = 'Smart home care monitor';
            app.SmarthomecaremonitorUIFigure.SizeChangedFcn = createCallbackFcn(app, @SmarthomecaremonitorUIFigureSizeChanged, true);
            app.HeaderPanel = uipanel(app.SmarthomecaremonitorUIFigure);
            app.AgeEditField = uieditfield(app.HeaderPanel, 'numeric');
            app.AgeEditField.Value = 55;
            app.UserEditField = uieditfield(app.HeaderPanel, 'text');
            app.HeightEditField = uieditfield(app.HeaderPanel, 'numeric');
            app.HeightEditField.Value = 172;
            app.WeightEditField = uieditfield(app.HeaderPanel, 'numeric');
            app.WeightEditField.Value = 65;
            app.UserLabel = uilabel(app.HeaderPanel);
            app.UserLabel.Text = 'User';
            app.HeightLabel = uilabel(app.HeaderPanel);
            app.HeightLabel.Text = 'Height';
            app.WeightLabel = uilabel(app.HeaderPanel);
            app.WeightLabel.Text = 'Weight';
            app.AgeLabel = uilabel(app.HeaderPanel);
            app.AgeLabel.Text = 'Age';
            app.TitleLabel = uilabel(app.HeaderPanel);
            app.TitleLabel.Text = 'Health Monitor';
            app.MainPanel = uipanel(app.SmarthomecaremonitorUIFigure);
            app.ButtonPanel = uipanel(app.MainPanel);
            app.ECGButton = uibutton(app.ButtonPanel, 'state');
            app.ECGButton.ValueChangedFcn = createCallbackFcn(app, @ECGButtonValueChanged, true);
            app.ECGButton.Text = '0 BPM';
            app.PPGLabel = uilabel(app.ButtonPanel);
            app.PPGLabel.Text = 'SPO2';
            app.PPGButton = uibutton(app.ButtonPanel, 'state');
            app.PPGButton.ValueChangedFcn = createCallbackFcn(app, @PPGButtonValueChanged, true);
            app.PPGButton.Text = '0 %';
            app.StepsLabel = uilabel(app.ButtonPanel);
            app.StepsLabel.Text = 'STEPS';
            app.StepsButton = uibutton(app.ButtonPanel, 'state');
            app.StepsButton.ValueChangedFcn = createCallbackFcn(app, @StepsButtonValueChanged, true);
            app.StepsButton.Text = '0 Steps';
            app.ECGLabel = uilabel(app.ButtonPanel);
            app.ECGLabel.Text = 'ECG';
            app.InfoPanel = uipanel(app.MainPanel);
            app.PPGPanel = uipanel(app.InfoPanel);
            app.PPGPanel.Visible = 'off';
            app.PPGAxesDetail1 = uiaxes(app.PPGPanel);
            app.PPGAxesDetail3 = uiaxes(app.PPGPanel);
            app.SPO2ValueEditField = uieditfield(app.PPGPanel, 'numeric');
            app.SPO2ValueEditField.Editable = 'off';
            app.PPGLowLamp = uilamp(app.PPGPanel);
            app.PPGNormalLamp = uilamp(app.PPGPanel);
            app.PPGStatisticsButtonGroup = uibuttongroup(app.PPGPanel);
            app.PPGStatisticsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PPGStatisticsButtonGroupSelectionChanged, true);
            app.PPGStatisticsButtonGroup.Title = 'Statistics';
            app.PPGButton30m = uiradiobutton(app.PPGStatisticsButtonGroup);
            app.PPGButton30m.Text = '1 min';
            app.PPGButton30m.Value = true;
            app.PPGButton2h = uiradiobutton(app.PPGStatisticsButtonGroup);
            app.PPGButton2h.Text = '10 min';
            app.PPGButton6h = uiradiobutton(app.PPGStatisticsButtonGroup);
            app.PPGButton6h.Text = '1 hour';
            app.PPGLastTimeLabel = uilabel(app.PPGPanel);
            app.PPGLastTimeLabel.Text = '30 mins';
            app.PPGMinEditField = uieditfield(app.PPGPanel, 'numeric');
            app.PPGMinEditField.Editable = 'off';
            app.PPGAvgEditField = uieditfield(app.PPGPanel, 'numeric');
            app.PPGAvgEditField.Editable = 'off';
            app.PPGAxesDetail2 = uiaxes(app.PPGPanel);
            app.PPGMaxEditField = uieditfield(app.PPGPanel, 'numeric');
            app.PPGMaxEditField.Editable = 'off';
            app.PPGMinLabel = uilabel(app.PPGPanel);
            app.PPGMinLabel.Text = 'Min';
            app.PPGMaxLabel = uilabel(app.PPGPanel);
            app.PPGMaxLabel.Text = 'Max';
            app.PPGAverageLabel = uilabel(app.PPGPanel);
            app.PPGAverageLabel.Text = 'Avg';
            app.PPGLowLabel = uilabel(app.PPGPanel);
            app.PPGLowLabel.Text = 'Low';
            app.PPGNormalLabel = uilabel(app.PPGPanel);
            app.PPGNormalLabel.Text = 'Normal';
            app.StepsPanel = uipanel(app.InfoPanel);
            app.StepsPanel.Title = 'Steps';
            app.StepsPanel.Visible = 'off';
            app.ECGPanel = uipanel(app.InfoPanel);
            app.ECGPanel.Visible = 'off';
            app.AvgEditField = uieditfield(app.ECGPanel, 'numeric');
            app.AvgEditField.Editable = 'off';
            app.ECGLastTimeLabel = uilabel(app.ECGPanel);
            app.ECGLastTimeLabel.Text = 'Last 30 min';
            app.MinEditField = uieditfield(app.ECGPanel, 'numeric');
            app.MinEditField.Editable = 'off';
            app.MaxEditField = uieditfield(app.ECGPanel, 'numeric');
            app.MaxEditField.Editable = 'off';
            app.LowLamp = uilamp(app.ECGPanel);
            app.NormalLamp = uilamp(app.ECGPanel);
            app.HighLamp = uilamp(app.ECGPanel);
            app.ECGAxesDetail = uiaxes(app.ECGPanel);
            xlabel(app.ECGAxesDetail, 'Time')
            ylabel(app.ECGAxesDetail, 'Heartbeat')
            app.ECGMinLabel = uilabel(app.ECGPanel);
            app.ECGMinLabel.Text = 'Min';
            app.ECGMaxLabel = uilabel(app.ECGPanel);
            app.ECGMaxLabel.Text = 'Max';
            app.ECGAvgLabel = uilabel(app.ECGPanel);
            app.ECGAvgLabel.Text = 'Avg';
            app.ECGLowLabel = uilabel(app.ECGPanel);
            app.ECGLowLabel.Text = 'Low';
            app.ECGNormalLabel = uilabel(app.ECGPanel);
            app.ECGNormalLabel.Text = 'Normal';
            app.ECGHighLabel = uilabel(app.ECGPanel);
            app.ECGHighLabel.Text = 'High';
            app.ECGStatisticsButtonGroup = uibuttongroup(app.ECGPanel);
            app.ECGStatisticsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ECGStatisticsButtonGroupSelectionChanged, true);
            app.ECGStatisticsButtonGroup.Title = 'Statistics';
            app.minButton = uiradiobutton(app.ECGStatisticsButtonGroup);
            app.minButton.Text = '30 min';
            app.minButton.Value = true;
            app.hrButton_3 = uiradiobutton(app.ECGStatisticsButtonGroup);
            app.hrButton_3.Text = '2 hr';
            app.hrButton_2 = uiradiobutton(app.ECGStatisticsButtonGroup);
            app.hrButton_2.Text = '6 hr';
            app.Label = uilabel(app.ECGPanel);
            app.Label.Text = '';
            app.Gauge = uigauge(app.ECGPanel, 'semicircular');
            app.Gauge.Limits = [0 240];
            app.Gauge.MajorTicks = [0 40 80 120 160 200 240];
            app.GraphPanel = uipanel(app.InfoPanel);
            app.GraphPanel.Visible = 'off';
            app.ECGAxesHome = uiaxes(app.GraphPanel);
            app.PPGAxesHome = uiaxes(app.GraphPanel);
            app.StepsAxesHome = uiaxes(app.GraphPanel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = HealthVision

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SmarthomecaremonitorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SmarthomecaremonitorUIFigure)
        end
    end
end