classdef HealthVision < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SmarthomecaremonitorUIFigure  matlab.ui.Figure
        HeaderPanel                   matlab.ui.container.Panel
        AgeEditField                  matlab.ui.control.NumericEditField
        UserEditField                 matlab.ui.control.EditField
        HeightEditField               matlab.ui.control.NumericEditField
        WeightEditField               matlab.ui.control.NumericEditField
        UserLabel                     matlab.ui.control.Label
        HeightLabel                   matlab.ui.control.Label
        WeightLabel                   matlab.ui.control.Label
        AgeLabel                      matlab.ui.control.Label
        TitleLabel                    matlab.ui.control.Label
        MainPanel                     matlab.ui.container.Panel
        ButtonPanel                   matlab.ui.container.Panel
        ECGButton                     matlab.ui.control.StateButton
        PPGLabel                      matlab.ui.control.Label
        PPGButton                     matlab.ui.control.StateButton
        StepsLabel                    matlab.ui.control.Label
        StepsButton                   matlab.ui.control.StateButton
        ECGLabel                      matlab.ui.control.Label
        InfoPanel                     matlab.ui.container.Panel
        PPGPanel                      matlab.ui.container.Panel
        ExtraInformationPPGPanel      matlab.ui.container.Panel
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes6                       matlab.ui.control.UIAxes
        SPO2ValueEditField            matlab.ui.control.NumericEditField
        LowLamp_2                     matlab.ui.control.Lamp
        NormalLamp_2                  matlab.ui.control.Lamp
        StatisticsButtonGroup         matlab.ui.container.ButtonGroup
        minButton_3                   matlab.ui.control.RadioButton
        minButton_4                   matlab.ui.control.RadioButton
        hourButton_2                  matlab.ui.control.RadioButton
        PPGLastTimeLabel              matlab.ui.control.Label
        MinEditField_2                matlab.ui.control.NumericEditField
        AvgEditField_2                matlab.ui.control.NumericEditField
        UIAxes4                       matlab.ui.control.UIAxes
        MaxEditField_2                matlab.ui.control.NumericEditField
        MinLabel                      matlab.ui.control.Label
        MaxLabel                      matlab.ui.control.Label
        AverageLabel                  matlab.ui.control.Label
        LowLabel                      matlab.ui.control.Label
        NormalLabel                   matlab.ui.control.Label
        StepsPanel                    matlab.ui.container.Panel
        ECGPanel                      matlab.ui.container.Panel
        ExtraInformationECGPanel      matlab.ui.container.Panel
        AvgEditField                  matlab.ui.control.NumericEditField
        ECGLastTimeLabel              matlab.ui.control.Label
        MinEditField                  matlab.ui.control.NumericEditField
        MaxEditField                  matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        Gauge                         matlab.ui.control.SemicircularGauge
        LowLamp                       matlab.ui.control.Lamp
        NormalLamp                    matlab.ui.control.Lamp
        HighLamp                      matlab.ui.control.Lamp
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes2_2                     matlab.ui.control.UIAxes
        UIAxes2_3                     matlab.ui.control.UIAxes
        ECGStatisticsButtonGroup      matlab.ui.container.ButtonGroup
        minButton                     matlab.ui.control.RadioButton
        hrButton_3                    matlab.ui.control.RadioButton
        hrButton_2                    matlab.ui.control.RadioButton
        ECGMinLabel                   matlab.ui.control.Label
        ECGMaxLabel                   matlab.ui.control.Label
        ECGAvgLabel                   matlab.ui.control.Label
        ECGLowLabel                   matlab.ui.control.Label
        ECGNormalLabel                matlab.ui.control.Label
        ECGHighLabel                  matlab.ui.control.Label
        GraphPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Value changed function: ECGButton
        function ECGButtonValueChanged(app, event)
            value = app.ECGButton.Value;
            if value == true
                app.ECGButton.BackgroundColor = [0.8, 0.8, 0.8];
                app.PPGButton.BackgroundColor = [0.15, 0.18, 0.23];
                app.StepsButton.BackgroundColor = [0.15, 0.18, 0.23];
                
                app.PPGButton.Value = false;
                app.StepsButton.Value = false;
                
                app.GraphPanel.Visible = 'off';
                app.PPGPanel.Visible = 'off';
                app.StepsPanel.Visible = 'off';
                
                app.ECGPanel.Visible = 'on';
            else
                app.ECGButton.BackgroundColor = [0.15, 0.18, 0.23];
                
                app.PPGPanel.Visible = 'off';
                app.GraphPanel.Visible = 'on';
            end
        end

        % Selection changed function: ECGStatisticsButtonGroup
        function ECGStatisticsButtonGroupSelectionChanged(app, event)
            selectedButton = app.ECGStatisticsButtonGroup.SelectedObject;
            if app.minButton.Value == true
                app.LastminuteLabel.Visible = 'on';
                app.Last10minutesLabel.Visible = 'off';
                app.ECGLastTimeLabel.Visible = 'off';
            elseif app.hrButton_3.Value == true
                app.LastminuteLabel.Visible = 'off';
                app.Last10minutesLabel.Visible = 'on';
                app.ECGLastTimeLabel.Visible = 'off';
            else
                app.LastminuteLabel.Visible = 'off';
                app.Last10minutesLabel.Visible = 'off';
                app.ECGLastTimeLabel.Visible = 'on';
            end
        end

        % Callback function
        function MoredetailsButtonPushed(app, event)
            app.ExtraInformationECGPanel.Visible = 'on';
            app.MoredetailsButton.Visible = 'off';
        end

        % Callback function
        function MoredetailsButton_2Pushed(app, event)
            app.ExtraInformationPPGPanel.Visible = 'on';
            app.MoredetailsButton_2.Visible = 'off';
        end

        % Value changed function: PPGButton
        function PPGButtonValueChanged(app, event)
            value = app.PPGButton.Value;
            if value == true
                app.PPGButton.BackgroundColor = [0.8, 0.8, 0.8];
                app.ECGButton.BackgroundColor = [0.15, 0.18, 0.23];
                app.StepsButton.BackgroundColor = [0.15, 0.18, 0.23];
                
                app.ECGButton.Value = false;
                app.StepsButton.Value = false;
                
                app.GraphPanel.Visible = 'off';
                app.ECGPanel.Visible = 'off';
                app.StepsPanel.Visible = 'off';
                
                app.PPGPanel.Visible = 'on';
            else
                app.PPGButton.BackgroundColor = [0.15, 0.18, 0.23];
                
                app.ECGPanel.Visible = 'off';
                app.GraphPanel.Visible = 'on';
            end
        end

        % Size changed function: SmarthomecaremonitorUIFigure
        function SmarthomecaremonitorUIFigureSizeChanged(app, event)
            position = app.SmarthomecaremonitorUIFigure.Position;
            
            w = position(3);
            h = position(4);
            
            % Automatically adjust GUI size
            
            
            % GUI size factor
            GUI_x = 0.0/1200.0;
            GUI_w = 1200.0/1200.0;
            GUI_y = 0.0/700.0;
            GUI_h = 700.0/700.0;
            
            
            % Header size factor
            Header_x = 0.0/1200.0;
            Header_w = 1200.0/1200.0;
            Header_y = 600.0/700.0;
            Header_h = 100.0/700.0;
            
            
            % Header Panel components
            Title_width = 213;
            Title_height = 47;
            Title_xpos = Header_w*w/2 - Title_width/2;
            Title_ypos = Header_h*h/2 - Title_height/2;
            
            UserEF_xpos = 100;
            UserEF_ypos = Header_h*h/2 - 15;
            UserEF_width = 200;
            UserEF_height = 31;
            
            UserL_width = 50;
            UserL_height = 31;
            UserL_xpos = UserEF_xpos - UserL_width;
            UserL_ypos = UserEF_ypos;
            
            inputEF_ySpace = 25;
            
            inputL_width = 50;
            inputL_height = 20;
            inputEF_width = 100;
            inputEF_height = 22;
            
            inputL_xpos = max([UserEF_xpos + UserEF_width, w - inputL_width - inputEF_width - 25]);
            inputL_ypos = Header_h*h/2 + inputEF_ySpace/2;
            inputEF_xpos = inputL_xpos + inputL_width;
            inputEF_ypos = Header_h*h/2 + inputEF_ySpace/2;
            
            
            % Main Panel size factor
            Main_x= 0.0/1200.0;
            Main_w = 1200.0/1200.0;
            Main_y= 0.0/700.0;
            Main_h = 600.0/700.0;
            
            
            % Button Panel size factor
            Button_x = 0.0/1200.0;
            Button_w = 220.0/1200.0;
            but_L_x = 10.0/1200.0;
            but_L_w = 72.0/1200.0;
            but_B_x = 85.0/1200.0;
            but_B_w = 125.0/1200.0;
            
            ySpace = 200.0/700.0;
            
            Button_y = 0.0/700.0;
            Button_h = 600.0/700.0;
            but_L_y = 80.0/700.0;
            but_L_h = 48.0/700.0;
            but_B_y = 50.0/700.0;
            but_B_h = 125.0/700.0;
            
            
            % Info Panel size factor
            Info_x = 220.0/1200.0;
            Info_w = 980.0/1200.0;
            subInfo_x = 0.0/1200.0;
            subInfo_w = 980.0/1200.0;
            Info_y = 0.0/700.0;
            Info_h = 600.0/700.0;
            subInfo_y = 0.0/700.0;
            subInfo_h = 600.0/700.0;
            
            graph_x = 15.0/1200.0;
            graph_w = 950.0/1200.0;
            graph_y = 25.0/700.0;
            graph_h = 175.0/700.0;
            
            
            % PPG
            PPG_UI3_x = 0.0/920.0;
            PPG_UI3_w = 700.0/920.0;
            PPG_UI3_y = 270.0/400.0;
            PPG_UI3_h = 120.0/400.0;
            
            PPG_UI4_x = 0.0/920.0;
            PPG_UI4_w = 700.0/920.0;
            PPG_UI4_y = 150.0/400.0;
            PPG_UI4_h = 120.0/400.0;
            
            PPG_UI6_x = 340.0/920.0;
            PPG_UI6_w = 550.0/920.0;
            PPG_UI6_y = 10.0/400.0;
            PPG_UI6_h = 150.0/400.0;
            
            PPG_SPO2EF_x = 700.0/920.0;
            PPG_SPO2EF_w = 80.0/920.0;
            PPG_SPO2EF_y = 250.0/400.0;
            PPG_SPO2EF_h =  80.0/400.0;
            
            PPG_MinEF_x = 210.0/920.0;
            PPG_MinEF_w = 100.0/920.0;
            PPG_MinEF_y = 100.0/400.0;
            PPG_MinEF_h = 30.0/400.0;
            
            PPG_MinL_x = 150.0/920.0;
            PPG_MinL_w = 50.0/920.0;
            PPG_MinL_y = 100.0/400.0;
            PPG_MinL_h = 32.0/400.0;
            
            PPG_AvgEF_x = 210.0/920.0;
            PPG_AvgEF_w = 100.0/920.0;
            PPG_AvgEF_y = 30.0/920.0;
            PPG_AvgEF_h = 30.0/400.0;
            
            PPG_AvgL_x = 150.0/920.0;
            PPG_AvgL_w = 50.0/920.0;
            PPG_AvgL_y = 30.0/400.0;
            PPG_AvgL_h = 32.0/400.0;
            
            PPG_MaxEF_x = 210.0/920.0;
            PPG_MaxEF_w = 100.0/920.0;
            PPG_MaxEF_y = 65.0/400.0;
            PPG_MaxEF_h = 30.0/400.0;
            
            PPG_MaxL_x = 150.0/920.0;
            PPG_MaxL_w = 50.0/920.0;
            PPG_MaxL_y = 65.0/400.0;
            PPG_MaxL_h = 32.0/400.0;
            
            PPG_LowLamp_x = 880.0/920.0;
            PPG_LowLamp_w = 20.0/920.0;
            PPG_LowLamp_y = 300.0/400.0;
            PPG_LowLamp_h = 20.0/400.0;
            
            PPG_LowL_x = 800.0/920.0;
            PPG_LowL_w = 50.0/920.0;
            PPG_LowL_y = 300.0/400.0;
            PPG_LowL_h = 32.0/400.0;
            
            PPG_NormalLamp_x = 880.0/920.0;
            PPG_NormalLamp_w = 20.0/920.0;
            PPG_NormalLamp_y = 270.0/400.0;
            PPG_NormalLamp_h = 20.0/400.0;
                        
            PPG_NormalL_x = 800.0/920.0;
            PPG_NormalL_w = 70.0/920.0;
            PPG_NormalL_y = 260.0/400.0;
            PPG_NormalL_h = 32.0/400.0;
            
            PPG_StatisticsBG_x = 10.0/920.0;
            PPG_StatisticsBG_w = 100.0/920.0;
            PPG_StatisticsBG_y = 30.0/400.0;
            PPG_StatisticsBG_h = 140.0/400.0;
            
            PPG_LastTime_x = 180.0/920.0;
            PPG_LastTime_w = 75.0/920.0;
            PPG_LastTime_y = 140.0/400.0;
            PPG_LastTime_h = 32.0/400.0;
           
      
            % ECG
            ECG_
            
            
            ECG_width = 916;
            ECG_height = 382;
            ECG_x = w*subInfo_w/2 - ECG_width/2;
            ECG_y = h*subInfo_h/2 - ECG_height/2;
            
            PPG_width = 925;
            PPG_height = 446;
            PPG_x = w*subInfo_w/2 - PPG_width/2;
            PPG_y = h*subInfo_h/2 - PPG_height/2;


            % Set the positions
            
            % 1) Header Panel
            app.HeaderPanel.Position = uint16([Header_x*w,Header_y*h,Header_w*w,Header_h*h]);
            
            app.TitleLabel.Position = uint16([Title_xpos, Title_ypos, Title_width, Title_height]);
            
            app.UserEditField.Position = uint16([UserEF_xpos, UserEF_ypos, UserEF_width, UserEF_height]);
            app.UserLabel.Position = uint16([UserL_xpos, UserL_ypos, UserL_width, UserL_height]);
            
            app.AgeEditField.Position = uint16([inputEF_xpos, inputEF_ypos, inputEF_width, inputEF_height]);
            app.HeightEditField.Position = uint16([inputEF_xpos, inputEF_ypos - inputEF_ySpace, inputEF_width, inputEF_height]);
            app.WeightEditField.Position = uint16([inputEF_xpos, inputEF_ypos - 2*inputEF_ySpace, inputEF_width, inputEF_height]);
            
            app.AgeLabel.Position = uint16([inputL_xpos, inputL_ypos, inputL_width, inputL_height]);
            app.HeightLabel.Position = uint16([inputL_xpos, inputL_ypos - inputEF_ySpace, inputL_width, inputL_height]);
            app.WeightLabel.Position = uint16([inputL_xpos, inputL_ypos - 2*inputEF_ySpace, inputL_width, inputL_height]);
            
            % 2) Button Panel
            app.MainPanel.Position = uint16([Main_x*w,Main_y*h,Main_w*w,Main_h*h]);
            app.ButtonPanel.Position = uint16([Button_x*w,Button_y*h,Button_w*w,Button_h*h]);
            
            app.ECGButton.Position = uint16([but_B_x*w,(but_B_y+2*ySpace)*h,but_B_w*w,but_B_h*h]);
            app.ECGLabel.Position = uint16([but_L_x*w,(but_L_y+2*ySpace)*h,but_L_w*w,but_L_h*h]);
            app.PPGButton.Position = uint16([but_B_x*w,(but_B_y+ySpace)*h,but_B_w*w,but_B_h*h]);
            app.PPGLabel.Position = uint16([but_L_x*w,(but_L_y+ySpace)*h,but_L_w*w,but_L_h*h]);
            app.StepsButton.Position = uint16([but_B_x*w,but_B_y*h,but_B_w*w,but_B_h*h]);
            app.StepsLabel.Position = uint16([but_L_x*w,but_L_y*h,but_L_w*w,but_L_h*h]);
            
            % 3) Visual Info Panel
            app.InfoPanel.Position = uint16([Info_x*w,Info_y*h,Info_w*w,Info_h*h]);
            
            % 3.1) Graph Panel
            app.GraphPanel.Position = uint16([subInfo_x*w,subInfo_y*h,subInfo_w*w,subInfo_h*h]);
            app.UIAxes.Position = uint16([graph_x*w,(graph_y+2*ySpace)*h,graph_w*w,graph_h*h]);
            app.UIAxes_2.Position = uint16([graph_x*w,(graph_y+ySpace)*h,graph_w*w,graph_h*h]);
            app.UIAxes_3.Position = uint16([graph_x*w,graph_y*h,graph_w*w,graph_h*h]);    
            
            % 3.2) PPG Panel
            app.PPGPanel.Position = uint16([subInfo_x*w,subInfo_y*h,subInfo_w*w,subInfo_h*h]);
            app.ExtraInformationPPGPanel.Position = uint16([PPG_x, PPG_y, PPG_width, PPG_height]);
            
            % 3.3) ECG Panel
            app.ECGPanel.Position = uint16([subInfo_x*w,subInfo_y*h,subInfo_w*w,subInfo_h*h]);
            app.ExtraInformationECGPanel.Position = uint16([ECG_x, ECG_y, ECG_width, ECG_height]);
        
            % 3.4) Steps Panel
            app.StepsPanel.Position = uint16([subInfo_x*w,subInfo_y*h,subInfo_w*w,subInfo_h*h]);


            %app.ECGButton.Text = 'hello';
            
            
        end

        % Selection changed function: StatisticsButtonGroup
        function StatisticsButtonGroupSelectionChanged(app, event)
            selectedButton = app.StatisticsButtonGroup.SelectedObject;
            if app.minButton_3.Value == true
                app.PPGLastTimeLabel.Visible = 'on';
                app.Last2hrLabel.Visible = 'off';
                app.Last6hrLabel.Visible = 'off';
            elseif app.minButton_4.Value == true
                app.PPGLastTimeLabel.Visible = 'off';
                app.Last2hrLabel.Visible = 'on';
                app.Last6hrLabel.Visible = 'off';
            else
                app.PPGLastTimeLabel.Visible = 'off';
                app.Last2hrLabel.Visible = 'off';
                app.Last6hrLabel.Visible = 'on';
            end 
        end

        % Value changed function: StepsButton
        function StepsButtonValueChanged(app, event)
            value = app.StepsButton.Value;
            if value == true
                app.StepsButton.BackgroundColor = [0.8, 0.8, 0.8];
                app.ECGButton.BackgroundColor = [0.15, 0.18, 0.23];
                app.PPGButton.BackgroundColor = [0.15, 0.18, 0.23];
                
                app.ECGButton.Value = false;
                app.PPGButton.Value = false;
                
                app.GraphPanel.Visible = 'off';
                app.PPGPanel.Visible = 'off';
                app.ECGPanel.Visible = 'off';
                
                app.StepsPanel.Visible = 'on';
            else
                app.StepsButton.BackgroundColor = [0.15, 0.18, 0.23];
                
                app.StepsPanel.Visible = 'off';
                app.GraphPanel.Visible = 'on';
            end
        end

        % Callback function
        function XButtonPushed(app, event)
            app.ExtraInformationECGPanel.Visible = 'off';
            app.MoredetailsButton.Visible = 'on'; 
        end

        % Callback function
        function XButton_2Pushed(app, event)
            app.ExtraInformationPPGPanel.Visible = 'off';
            app.MoredetailsButton_2.Visible = 'on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SmarthomecaremonitorUIFigure = uifigure;
            app.SmarthomecaremonitorUIFigure.Name = 'Smart home care monitor';
            app.SmarthomecaremonitorUIFigure.SizeChangedFcn = createCallbackFcn(app, @SmarthomecaremonitorUIFigureSizeChanged, true);
            app.HeaderPanel = uipanel(app.SmarthomecaremonitorUIFigure);
            app.AgeEditField = uieditfield(app.HeaderPanel, 'numeric');
            app.AgeEditField.Value = 55;
            app.UserEditField = uieditfield(app.HeaderPanel, 'text');
            app.HeightEditField = uieditfield(app.HeaderPanel, 'numeric');
            app.HeightEditField.Value = 172;
            app.WeightEditField = uieditfield(app.HeaderPanel, 'numeric');
            app.WeightEditField.Value = 65;
            app.UserLabel = uilabel(app.HeaderPanel);
            app.UserLabel.Text = 'User';
            app.HeightLabel = uilabel(app.HeaderPanel);
            app.HeightLabel.Text = 'Height';
            app.WeightLabel = uilabel(app.HeaderPanel);
            app.WeightLabel.Text = 'Weight';
            app.AgeLabel = uilabel(app.HeaderPanel);
            app.AgeLabel.Text = 'Age';
            app.TitleLabel = uilabel(app.HeaderPanel);
            app.TitleLabel.Text = 'Health Monitor';
            app.MainPanel = uipanel(app.SmarthomecaremonitorUIFigure);
            app.ButtonPanel = uipanel(app.MainPanel);
            app.ECGButton = uibutton(app.ButtonPanel, 'state');
            app.ECGButton.ValueChangedFcn = createCallbackFcn(app, @ECGButtonValueChanged, true);
            app.ECGButton.Text = '0 BPM';
            app.PPGLabel = uilabel(app.ButtonPanel);
            app.PPGLabel.Text = 'SPO2';
            app.PPGButton = uibutton(app.ButtonPanel, 'state');
            app.PPGButton.ValueChangedFcn = createCallbackFcn(app, @PPGButtonValueChanged, true);
            app.PPGButton.Text = '0 %';
            app.StepsLabel = uilabel(app.ButtonPanel);
            app.StepsLabel.Text = 'STEPS';
            app.StepsButton = uibutton(app.ButtonPanel, 'state');
            app.StepsButton.ValueChangedFcn = createCallbackFcn(app, @StepsButtonValueChanged, true);
            app.StepsButton.Text = '0 Steps';
            app.ECGLabel = uilabel(app.ButtonPanel);
            app.ECGLabel.Text = 'ECG';
            app.InfoPanel = uipanel(app.MainPanel);
            app.PPGPanel = uipanel(app.InfoPanel);
            app.PPGPanel.Visible = 'off';
            app.ExtraInformationPPGPanel = uipanel(app.PPGPanel);
            app.ExtraInformationPPGPanel.Title = 'Extra Information - PPG';
            app.UIAxes3 = uiaxes(app.ExtraInformationPPGPanel);
            app.UIAxes6 = uiaxes(app.ExtraInformationPPGPanel);
            app.SPO2ValueEditField = uieditfield(app.ExtraInformationPPGPanel, 'numeric');
            app.SPO2ValueEditField.Editable = 'off';
            app.LowLamp_2 = uilamp(app.ExtraInformationPPGPanel);
            app.NormalLamp_2 = uilamp(app.ExtraInformationPPGPanel);
            app.StatisticsButtonGroup = uibuttongroup(app.ExtraInformationPPGPanel);
            app.StatisticsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StatisticsButtonGroupSelectionChanged, true);
            app.StatisticsButtonGroup.Title = 'Statistics';
            app.minButton_3 = uiradiobutton(app.StatisticsButtonGroup);
            app.minButton_3.Text = '1 min';
            app.minButton_3.Value = true;
            app.minButton_4 = uiradiobutton(app.StatisticsButtonGroup);
            app.minButton_4.Text = '10 min';
            app.hourButton_2 = uiradiobutton(app.StatisticsButtonGroup);
            app.hourButton_2.Text = '1 hour';
            app.PPGLastTimeLabel = uilabel(app.ExtraInformationPPGPanel);
            app.PPGLastTimeLabel.Text = '30 mins';
            app.MinEditField_2 = uieditfield(app.ExtraInformationPPGPanel, 'numeric');
            app.MinEditField_2.Editable = 'off';
            app.AvgEditField_2 = uieditfield(app.ExtraInformationPPGPanel, 'numeric');
            app.AvgEditField_2.Editable = 'off';
            app.UIAxes4 = uiaxes(app.ExtraInformationPPGPanel);
            app.MaxEditField_2 = uieditfield(app.ExtraInformationPPGPanel, 'numeric');
            app.MaxEditField_2.Editable = 'off';
            app.MinLabel = uilabel(app.ExtraInformationPPGPanel);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.ExtraInformationPPGPanel);
            app.MaxLabel.Text = 'Max';
            app.AverageLabel = uilabel(app.ExtraInformationPPGPanel);
            app.AverageLabel.Text = 'Avg';
            app.LowLabel = uilabel(app.ExtraInformationPPGPanel);
            app.LowLabel.Text = 'Low';
            app.NormalLabel = uilabel(app.ExtraInformationPPGPanel);
            app.NormalLabel.Text = 'Normal';
            app.StepsPanel = uipanel(app.InfoPanel);
            app.StepsPanel.Title = 'Steps';
            app.StepsPanel.Visible = 'off';
            app.ECGPanel = uipanel(app.InfoPanel);
            app.ExtraInformationECGPanel = uipanel(app.ECGPanel);
            app.ExtraInformationECGPanel.Title = 'Extra Information - ECG';
            app.AvgEditField = uieditfield(app.ExtraInformationECGPanel, 'numeric');
            app.ECGLastTimeLabel = uilabel(app.ExtraInformationECGPanel);
            app.ECGLastTimeLabel.Text = 'Last 30 min';
            app.MinEditField = uieditfield(app.ExtraInformationECGPanel, 'numeric');
            app.MaxEditField = uieditfield(app.ExtraInformationECGPanel, 'numeric');
            app.Label = uilabel(app.ExtraInformationECGPanel);
            app.Label.Text = '';
            app.Gauge = uigauge(app.ExtraInformationECGPanel, 'semicircular');
            app.Gauge.Limits = [0 240];
            app.Gauge.MajorTicks = [0 40 80 120 160 200 240];
            app.LowLamp = uilamp(app.ExtraInformationECGPanel);
            app.NormalLamp = uilamp(app.ExtraInformationECGPanel);
            app.HighLamp = uilamp(app.ExtraInformationECGPanel);
            app.UIAxes2 = uiaxes(app.ExtraInformationECGPanel);
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Heartbeat')
            app.UIAxes2_2 = uiaxes(app.ExtraInformationECGPanel);
            xlabel(app.UIAxes2_2, 'Time')
            ylabel(app.UIAxes2_2, 'Heartbeat')
            app.UIAxes2_3 = uiaxes(app.ExtraInformationECGPanel);
            xlabel(app.UIAxes2_3, 'Time')
            ylabel(app.UIAxes2_3, 'Heart Rate')
            app.ECGStatisticsButtonGroup = uibuttongroup(app.ExtraInformationECGPanel);
            app.ECGStatisticsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ECGStatisticsButtonGroupSelectionChanged, true);
            app.ECGStatisticsButtonGroup.Title = 'Statistics';
            app.minButton = uiradiobutton(app.ECGStatisticsButtonGroup);
            app.minButton.Text = '30 min';
            app.minButton.Value = true;
            app.hrButton_3 = uiradiobutton(app.ECGStatisticsButtonGroup);
            app.hrButton_3.Text = '2 hr';
            app.hrButton_2 = uiradiobutton(app.ECGStatisticsButtonGroup);
            app.hrButton_2.Text = '6 hr';
            app.ECGMinLabel = uilabel(app.ExtraInformationECGPanel);
            app.ECGMinLabel.Text = 'Min';
            app.ECGMaxLabel = uilabel(app.ExtraInformationECGPanel);
            app.ECGMaxLabel.Text = 'Max';
            app.ECGAvgLabel = uilabel(app.ExtraInformationECGPanel);
            app.ECGAvgLabel.Text = 'Avg';
            app.ECGLowLabel = uilabel(app.ExtraInformationECGPanel);
            app.ECGLowLabel.Text = 'Low';
            app.ECGNormalLabel = uilabel(app.ExtraInformationECGPanel);
            app.ECGNormalLabel.Text = 'Normal';
            app.ECGHighLabel = uilabel(app.ExtraInformationECGPanel);
            app.ECGHighLabel.Text = 'High';
            app.GraphPanel = uipanel(app.InfoPanel);
            app.GraphPanel.Visible = 'off';
            app.UIAxes = uiaxes(app.GraphPanel);
            app.UIAxes_2 = uiaxes(app.GraphPanel);
            app.UIAxes_3 = uiaxes(app.GraphPanel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = HealthVision

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SmarthomecaremonitorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SmarthomecaremonitorUIFigure)
        end
    end
end